classdef DemoGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DemoUIFigure       matlab.ui.Figure
        InputLabel         matlab.ui.control.Label
        FilePathLabel      matlab.ui.control.Label
        showFilePath       matlab.ui.control.EditField
        selectFile         matlab.ui.control.Button
        SignalPara         matlab.ui.control.Label
        unit               matlab.ui.control.DropDown
        PlotPara           matlab.ui.control.Label
        TimeUnitselect     matlab.ui.control.DropDown
        FreUint            matlab.ui.control.Label
        Timeunit           matlab.ui.control.Label
        PlotLabel          matlab.ui.control.Label
        TimePlot           matlab.ui.control.UIAxes
        FrePlot            matlab.ui.control.UIAxes
        OutputLabel        matlab.ui.control.Label
        Modulation         matlab.ui.control.Label
        SignalFre          matlab.ui.control.Label
        SymbolRate         matlab.ui.control.Label
        SpreadSymbol       matlab.ui.control.Label
        SampleRateLabel    matlab.ui.control.Label
        SampleRate         matlab.ui.control.NumericEditField
        FFTEditFieldLabel  matlab.ui.control.Label
        inputFFTPoint      matlab.ui.control.NumericEditField
        selectDataFormat   matlab.ui.control.DropDown
        DataFormatLabel    matlab.ui.control.Label
        Button_2           matlab.ui.control.Button
        Button             matlab.ui.control.Button
        ButtonGroup        matlab.ui.container.ButtonGroup
        ZoomIn             matlab.ui.control.StateButton
        SaveButton         matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        ConsoleLabel       matlab.ui.control.Label
        Label              matlab.ui.control.Label
    end

    
    properties (Access = public)
        FilePath = 'data.bin'; % Description
        srv = 200e3;
        DataFormat = 'int16';
        FFTPoint = 1024;
        StartButtonState = 0;
        breakFlag = 0;
        sF = 'image1.png'
        ConsoleText = '';
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.showFilePath.Value = app.FilePath;
            %%--- 控制窗口位置居中 ---%%
            ScreenSize = get(0,'ScreenSize');
            FigureSize = [100, 100, 1360, 848];
            FigurePosition_left =  0.5 * (ScreenSize(3)  - FigureSize(3));
            FigurePosition_bottom = 0.75 * (ScreenSize(4) - FigureSize(2) - FigureSize(4));
            app.DemoUIFigure.Position = [FigurePosition_left, FigurePosition_bottom, FigureSize(3), FigureSize(4)];
            app.TextArea.Value = app.ConsoleText;
        end

        % Button pushed function: selectFile
        function getFilePath(app, event)
            FilterSpec = '*.bin';
            [FileName, PathName] = uigetfile(FilterSpec);
            app.FilePath = fullfile(PathName, FileName);
            app.showFilePath.Value = fullfile(PathName, FileName);
        end

        % Value changed function: SampleRate, unit
        function SampleRateUnitValueChanged(app, event)
            value = app.unit.Value;
            switch value
                case {'1000', '1', '1000000'}
                    value = str2double(value);
                    app.srv = app.SampleRate.Value;
                    app.srv = app.srv * value;
            end
        end

        % Value changed function: TimeUnitselect
        function TimeUnitselectValueChanged(app, event)
            value = app.TimeUnitselect.Value;
            switch value
                case {'    秒/s', '   毫秒/ms', '   微秒/us'}
                    value = deblank(value);
                    value = regexprep(value, '^.*/', '时间 / ');
                    xlabel(app.TimePlot, value);
            end
        end

        % Value changed function: selectDataFormat
        function selectDataFormatValueChanged(app, event)
            value = app.selectDataFormat.Value;
            app.DataFormat = value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %                sbv = app.StartButtonState;
            %                sbv = sbv + 1;
            %                app.Button.Text = 'Start';
            app.Button.Enable = 'on';
            app.Button.Visible = 'on';
            app.Button_2.Enable = 'off';
            app.Button_2.Visible = 'off';
            app.ZoomIn.Enable = 'on';
            app.SaveButton.Enable = 'on';
            app.breakFlag = 1;
            
        end

        % Button pushed function: Button
        function StartButtonPushed(app, event)

            app.Button_2.Enable = 'on';
            app.Button_2.Visible = 'on';
            app.Button.Visible = 'off';
            app.Button.Enable = 'off';
            app.ZoomIn.Enable = 'off';
            app.SaveButton.Enable = 'off';
            LFMProcessing;
        end

        % Value changed function: ZoomIn
        function ZoomInValueChanged(app, event)
            value = app.ZoomIn.Value;
            if value
                pan(app.TimePlot,'on');
                zoom(app.TimePlot,'on');
                pan(app.FrePlot,'on');
                zoom(app.FrePlot,'on');
            else
                pan(app.TimePlot,'off');
                zoom(app.TimePlot,'off');
                pan(app.FrePlot,'off');
                zoom(app.FrePlot,'off');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename, pathname] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
                '*.*','All Files' },'Save Image');
            if ~isequal(filename,0) || isequal(pathname,0)
                app.sF = fullfile(pathname,filename);
                outputFile = app.sF;
                robo = java.awt.Robot;
                ScreenSize = get(0,'ScreenSize');
                FigureSize = [100, 100, 1360, 848];
                FigurePosition_left =  0.5 * (ScreenSize(3)  - FigureSize(3));
                FigurePosition_bottom = 0.75 * (ScreenSize(4) - FigureSize(2) - FigureSize(4));
                x = FigurePosition_left + app.TimePlot.Position(1);
                y =  FigurePosition_bottom * 0.96 ;
                w = 593;
                h = 520;
                rectangle = java.awt.Rectangle(x,y,w,h);
                image1 = robo.createScreenCapture(rectangle);
                filehandle = java.io.File(outputFile);
                javax.imageio.ImageIO.write(image1,'jpg',filehandle);
                app.ZoomIn.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DemoUIFigure = uifigure;
            app.DemoUIFigure.Name = '520Demo';
            app.InputLabel = uilabel(app.DemoUIFigure);
            app.InputLabel.Text = 'Input';
            app.FilePathLabel = uilabel(app.DemoUIFigure);
            app.FilePathLabel.Text = '文件路径';
            app.showFilePath = uieditfield(app.DemoUIFigure, 'text');
            app.selectFile = uibutton(app.DemoUIFigure, 'push');
            app.selectFile.ButtonPushedFcn = createCallbackFcn(app, @getFilePath, true);
            app.selectFile.Text = '...';
            app.SignalPara = uilabel(app.DemoUIFigure);
            app.SignalPara.Text = '信号参数';
            app.unit = uidropdown(app.DemoUIFigure);
            app.unit.Items = {'      kHz', '       Hz', '     MHz'};
            app.unit.ItemsData = {'1000', '1', '1000000'};
            app.unit.ValueChangedFcn = createCallbackFcn(app, @SampleRateUnitValueChanged, true);
            app.unit.Value = '1000';
            app.PlotPara = uilabel(app.DemoUIFigure);
            app.PlotPara.Text = '绘图参数';
            app.TimeUnitselect = uidropdown(app.DemoUIFigure);
            app.TimeUnitselect.Items = {'      秒/s', '  毫秒/ms', '  微秒/us'};
            app.TimeUnitselect.ItemsData = {'    秒/s', '   毫秒/ms', '   微秒/us'};
            app.TimeUnitselect.ValueChangedFcn = createCallbackFcn(app, @TimeUnitselectValueChanged, true);
            app.TimeUnitselect.Value = '   毫秒/ms';
            app.FreUint = uilabel(app.DemoUIFigure);
            app.FreUint.Text = '频率单位';
            app.Timeunit = uilabel(app.DemoUIFigure);
            app.Timeunit.Text = '时间单位';
            app.PlotLabel = uilabel(app.DemoUIFigure);
            app.PlotLabel.Text = 'Plot';
            app.TimePlot = uiaxes(app.DemoUIFigure);
            title(app.TimePlot, '时域波形')
            xlabel(app.TimePlot, '时间 / s')
            ylabel(app.TimePlot, '幅度')
            app.FrePlot = uiaxes(app.DemoUIFigure);
            title(app.FrePlot, '频域波形')
            xlabel(app.FrePlot, '频率 / Hz')
            ylabel(app.FrePlot, '幅度')
            app.OutputLabel = uilabel(app.DemoUIFigure);
            app.OutputLabel.Text = 'Output';
            app.Modulation = uilabel(app.DemoUIFigure);
            app.Modulation.Text = '调制方式';
            app.SignalFre = uilabel(app.DemoUIFigure);
            app.SignalFre.Text = '信号频率';
            app.SymbolRate = uilabel(app.DemoUIFigure);
            app.SymbolRate.Text = '码元速率';
            app.SpreadSymbol = uilabel(app.DemoUIFigure);
            app.SpreadSymbol.Text = '扩频码';
            app.SampleRateLabel = uilabel(app.DemoUIFigure);
            app.SampleRateLabel.Text = '采样频率';
            app.SampleRate = uieditfield(app.DemoUIFigure, 'numeric');
            app.SampleRate.RoundFractionalValues = 'on';
            app.SampleRate.ValueChangedFcn = createCallbackFcn(app, @SampleRateUnitValueChanged, true);
            app.SampleRate.Value = 200;
            app.FFTEditFieldLabel = uilabel(app.DemoUIFigure);
            app.FFTEditFieldLabel.Text = 'FFT 点数';
            app.inputFFTPoint = uieditfield(app.DemoUIFigure, 'numeric');
            app.inputFFTPoint.Limits = [0 Inf];
            app.inputFFTPoint.RoundFractionalValues = 'on';
            app.inputFFTPoint.ValueDisplayFormat = '%.0f';
            app.inputFFTPoint.Value = 1024;
            app.selectDataFormat = uidropdown(app.DemoUIFigure);
            app.selectDataFormat.Items = {'   int16', '  double'};
            app.selectDataFormat.ItemsData = {'int16', 'double'};
            app.selectDataFormat.Editable = 'on';
            app.selectDataFormat.ValueChangedFcn = createCallbackFcn(app, @selectDataFormatValueChanged, true);
            app.selectDataFormat.Value = 'int16';
            app.DataFormatLabel = uilabel(app.DemoUIFigure);
            app.DataFormatLabel.Text = '数据格式';
            app.Button_2 = uibutton(app.DemoUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'stop.png';
            app.Button_2.Visible = 'off';
            app.Button_2.Text = '';
            app.Button = uibutton(app.DemoUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Button.Icon = 'start.png';
            app.Button.Text = '';
            app.ButtonGroup = uibuttongroup(app.DemoUIFigure);
            app.ZoomIn = uibutton(app.ButtonGroup, 'state');
            app.ZoomIn.ValueChangedFcn = createCallbackFcn(app, @ZoomInValueChanged, true);
            app.ZoomIn.Enable = 'off';
            app.ZoomIn.Icon = 'ZoomIn.png';
            app.ZoomIn.Text = '';
            app.SaveButton = uibutton(app.ButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = '';
            app.TextArea = uitextarea(app.DemoUIFigure);
            app.ConsoleLabel = uilabel(app.DemoUIFigure);
            app.ConsoleLabel.Text = 'Console';
            app.Label = uilabel(app.DemoUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = DemoGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DemoUIFigure)
        end
    end
end