classdef DemoGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DemoUIFigure          matlab.ui.Figure
        InputLabel            matlab.ui.control.Label
        FilePathLabel         matlab.ui.control.Label
        showFilePath          matlab.ui.control.EditField
        selectFile            matlab.ui.control.Button
        SignalPara            matlab.ui.control.Label
        unit                  matlab.ui.control.DropDown
        PlotPara              matlab.ui.control.Label
        TimeUnitselect        matlab.ui.control.DropDown
        FreUint               matlab.ui.control.Label
        Timeunit              matlab.ui.control.Label
        PlotLabel             matlab.ui.control.Label
        TimePlot              matlab.ui.control.UIAxes
        FrePlot               matlab.ui.control.UIAxes
        OutputLabel           matlab.ui.control.Label
        Modulation            matlab.ui.control.Label
        SignalFre             matlab.ui.control.Label
        SymbolRate            matlab.ui.control.Label
        SpreadingCode         matlab.ui.control.Label
        SampleRateLabel       matlab.ui.control.Label
        SampleRate            matlab.ui.control.NumericEditField
        FFTEditFieldLabel     matlab.ui.control.Label
        inputFFTPoint         matlab.ui.control.NumericEditField
        selectDataFormat      matlab.ui.control.DropDown
        DataFormatLabel       matlab.ui.control.Label
        StopButton            matlab.ui.control.Button
        StartButton           matlab.ui.control.Button
        ButtonGroup           matlab.ui.container.ButtonGroup
        ZoomIn                matlab.ui.control.StateButton
        SaveButton            matlab.ui.control.Button
        SymbolRateField       matlab.ui.control.NumericEditField
        SymbolRateUnit        matlab.ui.control.Label
        SigFreField           matlab.ui.control.NumericEditField
        SigFreUnit            matlab.ui.control.Label
        ModulationText        matlab.ui.control.Label
        SpreadingCodePlotAxe  matlab.ui.control.UIAxes
        ConsoleLabel          matlab.ui.control.Label
        SymbolCycleLabel      matlab.ui.control.Label
        SymbolCycleEditField  matlab.ui.control.NumericEditField
        PNCodeText            matlab.ui.control.TextArea
        ConsoleEditField      matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        FilePath = 'data.bin'; % Description
        srv = 200e3;
        DataFormat = 'int16';
        FFTPoint = 1024;
        breakFlag = 0;
        sF = 'image1.png'
        ConsoleValue = {};
    end
    
    methods (Access = public)
        
        function aDUFPo = UILoc(app)
            ScreenSize = get(0,'ScreenSize');
            FigureSize = [100, 100, 1394, 848];
            FigurePosition_left =  0.5 * (ScreenSize(3)  - FigureSize(3));
            FigurePosition_bottom = 0.72 * (ScreenSize(4) - FigureSize(2) - FigureSize(4));
            app.DemoUIFigure.Position = [FigurePosition_left, FigurePosition_bottom, FigureSize(3), FigureSize(4)];
            aDUFPo = [FigurePosition_left, FigurePosition_bottom, FigureSize(3), FigureSize(4)];
        end
        
        function SpreadingCodeAnalysis(app)
            %             PNCode = m_squence([0 1 1 0 1 1]);
            %             plot(app.PN, PNCode);
            %             tcf('D:\Matlab_file\huangningjie\pn31_cycle_signal_seq.mat');
            %             cycle = reprocessed_power_spectrum('D:\Matlab_file\huangningjie\pn31_cycle_signal_seq.mat');
            %             plot(app.PNCodeePlotAxe, cycle);
            %             xlim(app.PNCodeePlotAxe, ([0, inf]));
            %             ylim(app.PNCodeePlotAxe, ([-1, -0.9]));
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.showFilePath.Value = app.FilePath;
            %%--- 控制窗口位置居中 ---%%
            UILoc(app);
        end

        % Button pushed function: selectFile
        function getFilePath(app, event)
            FilterSpec = '*.bin';
            [FileName, PathName] = uigetfile(FilterSpec);
            app.FilePath = fullfile(PathName, FileName);
            app.showFilePath.Value = fullfile(PathName, FileName);
        end

        % Value changed function: SampleRate, unit
        function SampleRateUnitValueChanged(app, event)
            value = app.unit.Value;
            switch value
                case {'1000', '1', '1000000'}
                    value = str2double(value);
                    app.srv = app.SampleRate.Value;
                    app.srv = app.srv * value;
            end
        end

        % Value changed function: TimeUnitselect
        function TimeUnitselectValueChanged(app, event)
            value = app.TimeUnitselect.Value;
            switch value
                case {'    秒/s', '   毫秒/ms', '   微秒/us'}
                    value = deblank(value);
                    value = regexprep(value, '^.*/', '时间 / ');
                    xlabel(app.TimePlot, value);
            end
        end

        % Value changed function: selectDataFormat
        function selectDataFormatValueChanged(app, event)
            value = app.selectDataFormat.Value;
            app.DataFormat = value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            cellen = length(app.ConsoleValue);
            app.ConsoleValue(cellen + 1) = {'>>>  User Stop'};
            app.ConsoleEditField.Value = app.ConsoleValue; 
            app.StartButton.Enable = 'on';
            app.StartButton.Visible = 'on';
            app.StopButton.Enable = 'off';
            app.StopButton.Visible = 'off';
            app.ZoomIn.Enable = 'on';
            app.SaveButton.Enable = 'on';
            app.breakFlag = 1;
            
            %             %% Temp test %%
            %             app.SigFreField.Value = 10;
            %             app.SymbolRateField.Value = 5;
            %             app.ModulationText.Text = 'BPSK';
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.ConsoleValue = {'>>>  Begin  '; app.showFilePath.Value; ''};
            app.ConsoleEditField.Value = app.ConsoleValue;
%             num = [1, 2,3, 4, 5, 6, 7,1, 2,3, 4, 5, 6, 7,1, 2,3, 4, 5, 6, 7,1, 2,3, 4, 5, 6, 7,1, 2,3, 4, 5, 6, 7 ];
%             ConsoleValue = num2str(num);
%             app.PNCodeText.Value =  ConsoleValue;
            app.StopButton.Enable = 'on';
            app.StopButton.Visible = 'on';
            app.StartButton.Visible = 'off';
            app.StartButton.Enable = 'off';
            app.ZoomIn.Enable = 'off';
            app.SaveButton.Enable = 'off';
            
            %             SpreadingCodeAnalysis;
            
            GUImain;
        end

        % Value changed function: ZoomIn
        function ZoomInValueChanged(app, event)
            value = app.ZoomIn.Value;
            if value
                pan(app.TimePlot,'on');
                zoom(app.TimePlot,'on');
                pan(app.FrePlot,'on');
                zoom(app.FrePlot,'on');
            else
                pan(app.TimePlot,'off');
                zoom(app.TimePlot,'off');
                pan(app.FrePlot,'off');
                zoom(app.FrePlot,'off');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            ScreenCapture;
            app.ZoomIn.Enable = 'on';
            %             [filename, pathname] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            %                 '*.*','All Files' },'Save Image');
            %             if ~isequal(filename,0) || isequal(pathname,0)
            %                 app.sF = fullfile(pathname,filename);
            %                 outputFile = app.sF;
            %                 robo = java.awt.Robot;
            %                 ScreenSize = get(0,'ScreenSize');
            %                 FigureSize = [100, 100, 1360, 848];
            %                 FigurePosition_left =  0.5 * (ScreenSize(3)  - FigureSize(3));
            %                 FigurePosition_bottom = 0.75 * (ScreenSize(4) - FigureSize(2) - FigureSize(4));
            %                 x = FigurePosition_left + app.TimePlot.Position(1);
            %                 y =  FigurePosition_bottom * 0.96 ;
            %                 w = 593;
            %                 h = 520;
            %                 rectangle = java.awt.Rectangle(x,0,w,h);
            %                 image1 = robo.createScreenCapture(rectangle);
            %                 filehandle = java.io.File(outputFile);
            %                 javax.imageio.ImageIO.write(image1,'jpg',filehandle);
            %                 app.ZoomIn.Enable = 'on';
            %             end
        end

        % Callback function
        function SpreadingCode2NumButtonPushed(app, event)
            app.ConsoleEditField.Value = 1010101010111;
            app.ConsoleEditField.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DemoUIFigure = uifigure;
            app.DemoUIFigure.Name = '520Demo';
            app.InputLabel = uilabel(app.DemoUIFigure);
            app.InputLabel.Text = 'Input';
            app.FilePathLabel = uilabel(app.DemoUIFigure);
            app.FilePathLabel.Text = '文件路径';
            app.showFilePath = uieditfield(app.DemoUIFigure, 'text');
            app.selectFile = uibutton(app.DemoUIFigure, 'push');
            app.selectFile.ButtonPushedFcn = createCallbackFcn(app, @getFilePath, true);
            app.selectFile.Text = '...';
            app.SignalPara = uilabel(app.DemoUIFigure);
            app.SignalPara.Text = '信号参数';
            app.unit = uidropdown(app.DemoUIFigure);
            app.unit.Items = {'      kHz', '       Hz', '     MHz'};
            app.unit.ItemsData = {'1000', '1', '1000000'};
            app.unit.ValueChangedFcn = createCallbackFcn(app, @SampleRateUnitValueChanged, true);
            app.unit.Value = '1000';
            app.PlotPara = uilabel(app.DemoUIFigure);
            app.PlotPara.Text = '绘图参数';
            app.TimeUnitselect = uidropdown(app.DemoUIFigure);
            app.TimeUnitselect.Items = {'      秒/s', '  毫秒/ms', '  微秒/us'};
            app.TimeUnitselect.ItemsData = {'    秒/s', '   毫秒/ms', '   微秒/us'};
            app.TimeUnitselect.ValueChangedFcn = createCallbackFcn(app, @TimeUnitselectValueChanged, true);
            app.TimeUnitselect.Value = '   毫秒/ms';
            app.FreUint = uilabel(app.DemoUIFigure);
            app.FreUint.Text = '频率单位';
            app.Timeunit = uilabel(app.DemoUIFigure);
            app.Timeunit.Text = '时间单位';
            app.PlotLabel = uilabel(app.DemoUIFigure);
            app.PlotLabel.Text = 'Plot';
            app.TimePlot = uiaxes(app.DemoUIFigure);
            title(app.TimePlot, '时域波形')
            xlabel(app.TimePlot, '时间 / s')
            ylabel(app.TimePlot, '幅度')
            app.FrePlot = uiaxes(app.DemoUIFigure);
            title(app.FrePlot, '频域波形')
            xlabel(app.FrePlot, '频率 / Hz')
            ylabel(app.FrePlot, '幅度')
            app.OutputLabel = uilabel(app.DemoUIFigure);
            app.OutputLabel.Text = 'Output';
            app.Modulation = uilabel(app.DemoUIFigure);
            app.Modulation.Text = '调制方式';
            app.SignalFre = uilabel(app.DemoUIFigure);
            app.SignalFre.Text = '信号频率';
            app.SymbolRate = uilabel(app.DemoUIFigure);
            app.SymbolRate.Text = '码元速率';
            app.SpreadingCode = uilabel(app.DemoUIFigure);
            app.SpreadingCode.Text = '扩频码';
            app.SampleRateLabel = uilabel(app.DemoUIFigure);
            app.SampleRateLabel.Text = '采样频率';
            app.SampleRate = uieditfield(app.DemoUIFigure, 'numeric');
            app.SampleRate.RoundFractionalValues = 'on';
            app.SampleRate.ValueChangedFcn = createCallbackFcn(app, @SampleRateUnitValueChanged, true);
            app.SampleRate.Value = 200;
            app.FFTEditFieldLabel = uilabel(app.DemoUIFigure);
            app.FFTEditFieldLabel.Text = 'FFT 点数';
            app.inputFFTPoint = uieditfield(app.DemoUIFigure, 'numeric');
            app.inputFFTPoint.Limits = [0 Inf];
            app.inputFFTPoint.RoundFractionalValues = 'on';
            app.inputFFTPoint.ValueDisplayFormat = '%.0f';
            app.inputFFTPoint.Value = 1024;
            app.selectDataFormat = uidropdown(app.DemoUIFigure);
            app.selectDataFormat.Items = {'   int16', '  double'};
            app.selectDataFormat.ItemsData = {'int16', 'double'};
            app.selectDataFormat.Editable = 'on';
            app.selectDataFormat.ValueChangedFcn = createCallbackFcn(app, @selectDataFormatValueChanged, true);
            app.selectDataFormat.Value = 'int16';
            app.DataFormatLabel = uilabel(app.DemoUIFigure);
            app.DataFormatLabel.Text = '数据格式';
            app.StopButton = uibutton(app.DemoUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop.png';
            app.StopButton.Visible = 'off';
            app.StopButton.Text = '';
            app.StartButton = uibutton(app.DemoUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'start.png';
            app.StartButton.Text = '';
            app.ButtonGroup = uibuttongroup(app.DemoUIFigure);
            app.ZoomIn = uibutton(app.ButtonGroup, 'state');
            app.ZoomIn.ValueChangedFcn = createCallbackFcn(app, @ZoomInValueChanged, true);
            app.ZoomIn.Enable = 'off';
            app.ZoomIn.Icon = 'ZoomIn.png';
            app.ZoomIn.Text = '';
            app.SaveButton = uibutton(app.ButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = '';
            app.SymbolRateField = uieditfield(app.DemoUIFigure, 'numeric');
            app.SymbolRateField.Limits = [0 Inf];
            app.SymbolRateField.Editable = 'off';
            app.SymbolRateField.Value = 1000;
            app.SymbolRateUnit = uilabel(app.DemoUIFigure);
            app.SymbolRateUnit.Text = 'bps';
            app.SigFreField = uieditfield(app.DemoUIFigure, 'numeric');
            app.SigFreField.Limits = [0 Inf];
            app.SigFreField.Editable = 'off';
            app.SigFreField.Value = 5;
            app.SigFreUnit = uilabel(app.DemoUIFigure);
            app.SigFreUnit.Text = 'kHz';
            app.ModulationText = uilabel(app.DemoUIFigure);
            app.ModulationText.Text = 'BPSK';
            app.SpreadingCodePlotAxe = uiaxes(app.DemoUIFigure);
            title(app.SpreadingCodePlotAxe, 'Cycle')
            app.SpreadingCodePlotAxe.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.SpreadingCodePlotAxe.XTickLabel = '';
            app.SpreadingCodePlotAxe.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.SpreadingCodePlotAxe.YTickLabel = '';
            app.ConsoleLabel = uilabel(app.DemoUIFigure);
            app.ConsoleLabel.Text = 'Console';
            app.SymbolCycleLabel = uilabel(app.DemoUIFigure);
            app.SymbolCycleLabel.Text = '码周期';
            app.SymbolCycleEditField = uieditfield(app.DemoUIFigure, 'numeric');
            app.SymbolCycleEditField.Limits = [0 Inf];
            app.SymbolCycleEditField.Editable = 'off';
            app.SymbolCycleEditField.Value = 31;
            app.PNCodeText = uitextarea(app.DemoUIFigure);
            app.PNCodeText.Editable = 'off';
            app.PNCodeText.Value = {'1 0 0 1 0 1 1 0 0 1 0 1 0 0 1 0 1 1 0 1 1 1 0 1 1 1 1 0 0 1 1 0'};
            app.ConsoleEditField = uitextarea(app.DemoUIFigure);
            app.ConsoleEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DemoGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DemoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DemoUIFigure)
        end
    end
end